classdef traffic_light_task3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        AutoButton      matlab.ui.control.Button
        manualButton    matlab.ui.control.Button
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
        manualPanel     matlab.ui.container.Panel
        redButton       matlab.ui.control.Button
        yellowButton    matlab.ui.control.Button
        greenButton     matlab.ui.control.Button
        autoPanel       matlab.ui.container.Panel
        Label           matlab.ui.control.Label
    end


    properties (Access = public)
   auto=false;
   ardu_obj=arduino();
   delay_var=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: greenButton
        function greenButtonPushed(app, event)
            writeDigitalPin(app.ardu_obj,"D7",0);
            writeDigitalPin(app.ardu_obj,"D6",0);
            writeDigitalPin(app.ardu_obj,"D5",1);
        end

        % Button pushed function: manualButton
        function manualButtonPushed(app, event)
            app.auto=false;
            app.manualPanel.Enable="on";
            app.autoPanel.Enable="off";
        end

        % Button pushed function: redButton
        function redButtonPushed(app, event)
            writeDigitalPin(app.ardu_obj,"D7",1);
            writeDigitalPin(app.ardu_obj,"D6",0);
            writeDigitalPin(app.ardu_obj,"D5",0);
        end

        % Button pushed function: yellowButton
        function yellowButtonPushed(app, event)
            writeDigitalPin(app.ardu_obj,"D7",0);
            writeDigitalPin(app.ardu_obj,"D6",1);
            writeDigitalPin(app.ardu_obj,"D5",0);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.auto=true;
            app.manualPanel.Enable="off";
            app.autoPanel.Enable="on";
            while(app.auto==true)
            app.delay_var=app.EditField.Value;
             while(app.auto==true&&app.delay_var>0)
                 app.Label.Text=num2str(app.delay_var);
                 app.delay_var=app.delay_var-1;
                 writeDigitalPin(app.ardu_obj,"D7",1);
                 writeDigitalPin(app.ardu_obj,"D6",0);
                 writeDigitalPin(app.ardu_obj,"D5",0);
                 pause(1);
                    
             end
             app.delay_var=app.EditField.Value;
             while(app.auto==true&&app.delay_var>0)
                 app.Label.Text=num2str(app.delay_var);
                 app.delay_var=app.delay_var-1;
                 writeDigitalPin(app.ardu_obj,"D7",0);
                 writeDigitalPin(app.ardu_obj,"D6",1);
                 writeDigitalPin(app.ardu_obj,"D5",0);
                 pause(1);
                    
             end
             app.delay_var=app.EditField.Value;
             while(app.auto==true&&app.delay_var>0)
                 app.Label.Text=num2str(app.delay_var);
                 app.delay_var=app.delay_var-1;
                 writeDigitalPin(app.ardu_obj,"D7",0);
                 writeDigitalPin(app.ardu_obj,"D6",0);
                 writeDigitalPin(app.ardu_obj,"D5",1);
                 pause(1);
                    
             end
             
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.autoPanel = uipanel(app.UIFigure);
            app.autoPanel.Title = 'auto';
            app.Label = uilabel(app.autoPanel);
            app.Label.Text = '';
            app.manualPanel = uipanel(app.UIFigure);
            app.manualPanel.Title = 'manual';
            app.greenButton = uibutton(app.manualPanel, 'push');
            app.greenButton.ButtonPushedFcn = createCallbackFcn(app, @greenButtonPushed, true);
            app.greenButton.Text = {'green'; ''};
            app.yellowButton = uibutton(app.manualPanel, 'push');
            app.yellowButton.ButtonPushedFcn = createCallbackFcn(app, @yellowButtonPushed, true);
            app.yellowButton.Text = {'yellow'; ''};
            app.redButton = uibutton(app.manualPanel, 'push');
            app.redButton.ButtonPushedFcn = createCallbackFcn(app, @redButtonPushed, true);
            app.redButton.Text = {'red'; ''};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.manualButton = uibutton(app.UIFigure, 'push');
            app.manualButton.ButtonPushedFcn = createCallbackFcn(app, @manualButtonPushed, true);
            app.manualButton.Text = {'manual'; ''};
            app.AutoButton = uibutton(app.UIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = {'Auto'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traffic_light_task3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end