classdef simple_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        caluculatorPanel  matlab.ui.container.Panel
        Result            matlab.ui.control.NumericEditField
        Second_number     matlab.ui.control.NumericEditField
        First_number      matlab.ui.control.NumericEditField
        Button_13         matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        Button_11         matlab.ui.control.Button
        Button_10         matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global s_0;
            s_0=1;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global s_1;
            s_1=2;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             global s_2;
            s_2=3;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             global s_3;
            s_3=4;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             global s_4;
            s_4=5;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             global s_5;
            s_5=6;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             global s_6;
            s_6=7;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             global s_7;
            s_7=8;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
             global s_8;
            s_8=9;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
             global s_9;
            s_9=0;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)

            a=app.First_numberValueChanged2;
            b=app.Second_numberValueChanged;
            r=a+b;
            app.ResultValueChanged2=r;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            global s_0;
            global s_1;
            global s_2;
            global s_3;
            global s_4;
            global s_5;
            global s_6;
            global s_7;
            
            global s_9;
            
            end
            
        end

        % Callback function
        function EditFieldValueChanged(app, event)

        end

        % Value changed function: Second_number
        function Second_numberValueChanged(app, event)
            
        end

        % Value changed function: First_number
        function First_numberValueChanged2(app, event)
            
        end

        % Value changed function: Result
        function ResultValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.caluculatorPanel = uipanel(app.UIFigure);
            app.caluculatorPanel.Title = 'caluculator';
            app.Button = uibutton(app.caluculatorPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {' 1'; ''};
            app.Button_2 = uibutton(app.caluculatorPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.caluculatorPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.caluculatorPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.caluculatorPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.caluculatorPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.caluculatorPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.caluculatorPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.caluculatorPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.caluculatorPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '0';
            app.Button_11 = uibutton(app.caluculatorPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '+';
            app.Button_12 = uibutton(app.caluculatorPanel, 'push');
            app.Button_12.Text = '-';
            app.Button_13 = uibutton(app.caluculatorPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '=';
            app.First_number = uieditfield(app.caluculatorPanel, 'numeric');
            app.First_number.ValueChangedFcn = createCallbackFcn(app, @First_numberValueChanged2, true);
            app.Second_number = uieditfield(app.caluculatorPanel, 'numeric');
            app.Second_number.ValueChangedFcn = createCallbackFcn(app, @Second_numberValueChanged, true);
            app.Result = uieditfield(app.caluculatorPanel, 'numeric');
            app.Result.ValueChangedFcn = createCallbackFcn(app, @ResultValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simple_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end